classdef ECG_real_time_signal_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGAnalysisSoftwareUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        FilteringDropDown               matlab.ui.control.DropDown
        FilterTypeKnob                  matlab.ui.control.DiscreteKnob
        OrderKnobLabel                  matlab.ui.control.Label
        OrderKnob                       matlab.ui.control.DiscreteKnob
        RpKnobLabel                     matlab.ui.control.Label
        RpKnob                          matlab.ui.control.DiscreteKnob
        RsKnobLabel                     matlab.ui.control.Label
        RsKnob                          matlab.ui.control.DiscreteKnob
        StatusLabel                     matlab.ui.control.Label
        Switch                          matlab.ui.control.RockerSwitch
        BPM                             matlab.ui.control.Label
        HeartImage                      matlab.ui.control.Image
        BPMLabel                        matlab.ui.control.Label
        ECGFFTPlot                      matlab.ui.control.UIAxes
        FFToftheECGSignalLabel          matlab.ui.control.Label
        StartButton                     matlab.ui.control.Image
        Wp1Spinner                      matlab.ui.control.Spinner
        Wp2Spinner                      matlab.ui.control.Spinner
        LowerCFLabel                    matlab.ui.control.Label
        HigherCFLabel                   matlab.ui.control.Label
        FilteringStatusLabel            matlab.ui.control.Label
        DigitalFiltersLabel             matlab.ui.control.Label
        StatusIcon                      matlab.ui.control.Image
        Fs                              matlab.ui.control.Label
        Bytes                           matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        BytesLabel                      matlab.ui.control.Label
        PortIcon                        matlab.ui.control.Image
        SystemOnOffButton               matlab.ui.control.Image
        COMLabel                        matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Image
        RecordingLabel                  matlab.ui.control.Label
        OfflineProcessingButton         matlab.ui.control.Image
        OfflineProcessingLabel          matlab.ui.control.Label
        RealtimeProcessingLabel         matlab.ui.control.Label
        ECGPlot                         matlab.ui.control.UIAxes
        RealtimeECGSignalLabel          matlab.ui.control.Label
        FilteredECGPlot                 matlab.ui.control.UIAxes
        RealtimeFilteredECGSignalLabel  matlab.ui.control.Label
        HRVButton                       matlab.ui.control.Image
        HRVLabel                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        systemOnOff = "off"; % Real-time processing is OFF by default
        comPort % Port info
        save_path % Save location
        filtering = 1; % Filterin is on by default
        filtering_options_changed = 0; % When filtering options changed, it becomes 1
        fs_fixed = 499.89; % Approximate sampling frequency by default
        offline_processing = 0; % Offline processing is 'off' by default
        ECG_offline
        time_offline
        plotFreqz = [];
        filteredOfflineECGPlot = [];
        recording = 0; % Recording is 'off' by default
        % Default lowpass filter coefficients at 20 Hz with a order of 3
        a = [1, -2.49860834469118, 2.11525412700316, -0.604109699507275];
        b = [0.00156701035058832, 0.00470103105176495, 0.00470103105176495, 0.00156701035058832];
        % Default lowpass filter coefficients at 13 Hz with a order of 3 (BPM)
        bpm_a = [1.0000 -2.6736 2.3984 -0.7210];
        bpm_b = [0.0005 0.0014 0.0014 0.0005];
        DialogApp_choose_port % Keep 'choose_port.mlapp' information
        DialogApp_choose_ecg % Keep 'choose_ecg.mlapp' information
        DialogApp_hrv_monitor % Keep 'hrv_monitor.mlapp' information
    end
    
    methods (Access = public)
        % Get the port information from 'choose_port.mlapp' and establish the
        % serial communication
        function updateCOM(app, comPort)
            app.comPort = comPort;
            app.COMLabel.Text = comPort;
            % Save new port to the 'configurations.mat' file
            configurations = matfile('configurations.mat', 'Writable', true);
            conf_temp = configurations.conf;
            conf_temp.port = comPort;
            configurations.conf = conf_temp;
        end
        %% OFFLINE PROCESSING
        % Get folder path and file name from 'choose_ecg.mlapp'
        function loadECG(app, path, file_name, start_time, end_time)
            % Update status
            app.StatusLabel.Text = 'Busy'; app.StatusIcon.ImageSource = 'waiting.gif'; drawnow;
            % Show HRV button
            app.HRVButton.Visible = 1; app.HRVLabel.Visible = 1;
            
            cd(path); % Open the directory
            file = dir(file_name); % Get the file
            load(file.name); % Load the file
            
            % Save the new load path to the 'configurations.mat' file
            configurations = matfile('configurations.mat', 'Writable', true);
            conf_temp = configurations.conf;
            conf_temp.load_path = path;
            configurations.conf = conf_temp;
            
            % Start the time from zero and convert it to seconds
            time = time - time(1); time = time/1000000;
            
            % Trim
            time(time > end_time) = [];
            ECG = ECG(1:length(time));
            time(time < start_time) = [];
            ECG(1:length(ECG)-length(time)) = [];
            ECG = normalize(ECG, 'range'); % Normalize
            app.ECG_offline = ECG; % To be used in filtering and HRV
            app.time_offline = time; % To be used in HRV
            % Calculate the sampling frequency
            fs = (length(ECG)-1)/(time(end)-time(1));
            % Plot the signal
            app.ECGPlot.XLim = [start_time end_time]; plot(app.ECGPlot, linspace(start_time, end_time, length(ECG)), ECG);
            % Plot the FFT
            cla(app.ECGFFTPlot);
            plot(app.ECGFFTPlot, linspace(0, fs, length(ECG)), normalize(abs(fft(normalize(ECG))), 'range'));
            % Plot the frequency response
            delete(app.plotFreqz);
            if (app.filtering)
                freq_response = freqz(app.b, app.a, 1250);
                app.plotFreqz = line(app.ECGFFTPlot,linspace(0,app.fs_fixed/2,1250),abs(freq_response), 'color', '#e04f5f');
            end
            % Plot the filtered signal
            app.FilteredECGPlot.XLim = [start_time end_time];
            filtered_ECG = normalize(filtfilt(app.b, app.a, ECG),'range');
            app.filteredOfflineECGPlot = plot(app.FilteredECGPlot, linspace(start_time, end_time, length(ECG)), filtered_ECG);
            % Calculate BPM
            ECG_peak = filtfilt(app.bpm_b, app.bpm_a, ECG); % Filtering
            ECG_peak = ECG_peak - movmean(ECG_peak, 70); % Linearization
            ECG_peak = ECG_peak.^3;
            ECG_peak(ECG_peak < 0) = 0;
            ECG_peak = normalize(ECG_peak, 'range');
            % Moving window bpm calculation
            locs = [];
            i = 1000;
            window = i;
            l = length(ECG_peak) + window;
            try
                while (true)
                    if l - i > window
                        [~, locs_temp] = findpeaks(normalize(ECG_peak(i-window+1:i), 'range'), fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                        locs = [locs (locs_temp + (i-window)/fs)];
                        i = i + window;
                    else
                        [~, locs_temp] = findpeaks(normalize(ECG_peak(i-window+1:end), 'range'), fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                        locs = [locs (locs_temp + (i-window)/fs)];
                        break;
                    end
                end
            catch
                locs = [];
                i = 1600;
                window = i;
                l = length(ECG_peak) + window;
                while (true)
                    if l - i > window
                        [~, locs_temp] = findpeaks(normalize(ECG_peak(i-window+1:i), 'range'), fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                        locs = [locs (locs_temp + (i-window)/fs)];
                        i = i + window;
                    else
                        [~, locs_temp] = findpeaks(normalize(ECG_peak(i-window+1:end), 'range'), fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                        locs = [locs (locs_temp + (i-window)/fs)];
                        break;
                    end
                end
            end
            if length(locs) > 1
                beats = 60*(length(locs)-1)/(locs(end) - locs(1));
                app.BPM.Text = num2str(round(beats));
            end
            locs = locs + 0.00601; % adding the delay caused by filtering
            peak_index = round(locs*fs) + 1;
            hold(app.ECGPlot, 'on')
            locs = locs + start_time;
            line(app.ECGPlot, repmat(locs,[2 1]),repmat([0.27; 0.73],size(locs)),'LineWidth',2.5,'LineStyle','-.','Color','r');
            % Update status
            app.StatusLabel.Text = 'Ready'; app.StatusIcon.ImageSource = 'ready.svg';
            delete(app.DialogApp_choose_ecg);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)
            % Get save location
            [~, result] = system('path');
            current_directory = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            app.save_path = convertCharsToStrings(current_directory) + '\save';
            
            % Load the "configurations.mat" file
            load("configurations.mat", "conf");
            % Get port information from 'configurations.mat' file
            app.comPort = conf.port;
            app.COMLabel.Text = app.comPort;
            
            % Set load location if it is not set
            if isempty(conf.load_path)
                % Save the new path to the 'configurations.mat' file
                configurations = matfile('configurations.mat', 'Writable', true);
                conf_temp = configurations.conf;
                conf_temp.load_path = app.save_path;
                configurations.conf = conf_temp;
            end            
            
            % Set line colors for the three UIAxes
            app.ECGPlot.ColorOrder =  [0.74, 1, 0.59];
            app.ECGFFTPlot.ColorOrder =  [0.77, 0.98, 1];
            app.FilteredECGPlot.ColorOrder =  [0.74, 1, 0.59];
            
            % Set axis limits
            app.ECGPlot.XLim = [0 5]; app.ECGPlot.YLim = [-0.2 1.2];
            app.FilteredECGPlot.XLim = [0 5]; app.FilteredECGPlot.YLim = [-0.2 1.2];
            
            % Update status
            app.StatusLabel.Text = 'Ready'; app.StatusIcon.ImageSource = 'ready.svg';
        end

        % Image clicked function: StartButton
        function execute(app, event)
            if app.filtering_options_changed == 1
                % Decision making
                switch app.FilteringDropDown.Value
                    case 'Butterworth'
                        switch app.FilterTypeKnob.Value
                            case 'Lowpass'
                                [app.b, app.a] = butter(str2double(app.OrderKnob.Value), app.Wp1Spinner.Value/(app.fs_fixed/2), "low");
                            case 'Highpass'
                                [app.b, app.a] = butter(str2double(app.OrderKnob.Value), app.Wp1Spinner.Value/(app.fs_fixed/2), "high");
                            case 'Bandstop'
                                [app.b, app.a] = butter(str2double(app.OrderKnob.Value), [app.Wp1Spinner.Value/(app.fs_fixed/2) app.Wp2Spinner.Value/(app.fs_fixed/2)], "stop");
                            case 'Bandpass'
                                [app.b, app.a] = butter(str2double(app.OrderKnob.Value), [app.Wp1Spinner.Value/(app.fs_fixed/2) app.Wp2Spinner.Value/(app.fs_fixed/2)], "bandpass");
                        end
                    case 'Chebychev'
                        switch app.FilterTypeKnob.Value
                            case 'Lowpass'
                                [app.b, app.a] = cheby1(str2double(app.OrderKnob.Value), str2double(app.RpKnob.Value), app.Wp1Spinner.Value/(app.fs_fixed/2), 'low');
                            case 'Highpass'
                                [app.b, app.a] = cheby1(str2double(app.OrderKnob.Value), str2double(app.RpKnob.Value), app.Wp1Spinner.Value/(app.fs_fixed/2), 'high');
                            case 'Bandstop'
                                [app.b, app.a] = cheby1(str2double(app.OrderKnob.Value), str2double(app.RpKnob.Value), [app.Wp1Spinner.Value/(app.fs_fixed/2) app.Wp2Spinner.Value/(app.fs_fixed/2)], 'stop');
                            case 'Bandpass'
                                [app.b, app.a] = cheby1(str2double(app.OrderKnob.Value), str2double(app.RpKnob.Value), [app.Wp1Spinner.Value/(app.fs_fixed/2) app.Wp2Spinner.Value/(app.fs_fixed/2)], 'bandpass');
                        end
                    case 'Elliptic'
                        switch app.FilterTypeKnob.Value
                            case 'Lowpass'
                                [app.b, app.a] = ellip(str2double(app.OrderKnob.Value), str2double(app.RpKnob.Value), str2double(app.RsKnob.Value), app.Wp1Spinner.Value/(app.fs_fixed/2), 'low');
                            case 'Highpass'
                                [app.b, app.a] = ellip(str2double(app.OrderKnob.Value), str2double(app.RpKnob.Value), str2double(app.RsKnob.Value), app.Wp1Spinner.Value/(app.fs_fixed/2), 'high');
                            case 'Bandstop'
                                [app.b, app.a] = ellip(str2double(app.OrderKnob.Value), str2double(app.RpKnob.Value), str2double(app.RsKnob.Value), [app.Wp1Spinner.Value/(app.fs_fixed/2) app.Wp2Spinner.Value/(app.fs_fixed/2)], 'stop');
                            case 'Bandpass'
                                [app.b, app.a] = ellip(str2double(app.OrderKnob.Value), str2double(app.RpKnob.Value), str2double(app.RsKnob.Value), [app.Wp1Spinner.Value/(app.fs_fixed/2) app.Wp2Spinner.Value/(app.fs_fixed/2)], 'bandpass');
                        end
                end
                app.filtering = 1; % Filtering is on
                app.filtering_options_changed = 0; % Filtering options is up to date
                % Change the button color and the text
                app.StartButton.ImageSource = 'filter_on.svg'; app.FilteringStatusLabel.Text = ' Filtering is on';
                delete(app.plotFreqz);
                freq_response = freqz(app.b, app.a, 1250);
                app.plotFreqz = line(app.ECGFFTPlot,linspace(0,app.fs_fixed/2,1250),abs(freq_response), 'color', '#e04f5f');
            elseif app.filtering == 0
                app.filtering = 1;
                % Change the button color and the text
                app.StartButton.ImageSource = 'filter_on.svg'; app.FilteringStatusLabel.Text = ' Filtering is on';
                delete(app.plotFreqz);
                freq_response = freqz(app.b, app.a, 1250);
                app.plotFreqz = line(app.ECGFFTPlot,linspace(0,app.fs_fixed/2,1250),abs(freq_response), 'color', '#e04f5f');
            else
                app.filtering = 0;
                % Change the button color and the text
                app.StartButton.ImageSource = 'filter_off.svg'; app.FilteringStatusLabel.Text = ' Filtering is off';
                cla(app.FilteredECGPlot); % Clear the plot
                delete(app.plotFreqz); % Clear the plot of frequency response
            end
            
            if app.filtering == 1 && app.offline_processing == 1
                % Plot the filtered signal
                limits = app.FilteredECGPlot.XLim;
                delete(app.filteredOfflineECGPlot);
                app.filteredOfflineECGPlot = line(app.FilteredECGPlot, linspace(limits(1), limits(2), length(app.ECG_offline)), normalize(filtfilt(app.b, app.a, app.ECG_offline), 'range'));
            end
        end

        % Value changed function: FilterTypeKnob, 
        % FilteringDropDown, OrderKnob, RpKnob, RsKnob, Wp1Spinner, 
        % Wp2Spinner
        function filteringOptionsChanged(app, event)
            app.filtering_options_changed = 1;
            % Change the button color and the text
            app.StartButton.ImageSource = 'filter_off.svg';
            app.FilteringStatusLabel.Text = ' Options changed';
        end

        % Image clicked function: PortIcon
        function SelectPort(app, event)
            % Update status
            app.StatusLabel.Text = 'Busy'; app.StatusIcon.ImageSource = 'waiting.gif'; drawnow;
            delete(app.DialogApp_choose_port);
            % Open choose_port.mlapp when the plug icon is clicked
            app.DialogApp_choose_port = choose_port(app);
            % Update status
            app.StatusLabel.Text = 'Ready'; app.StatusIcon.ImageSource = 'ready.svg';
        end

        % Image clicked function: SystemOnOffButton
        function SystemOnOffButtonClicked(app, event)
            if app.systemOnOff == "off"
                % Update status
                app.StatusLabel.Text = 'Busy'; app.StatusIcon.ImageSource = 'waiting.gif'; drawnow;
                if app.recording == 0
                    app.StatusLabel.Text = 'Not recording'; app.StatusIcon.ImageSource = 'not_recording.svg';
                else
                    app.StatusLabel.Text = 'Recording'; app.StatusIcon.ImageSource = 'record.svg';
                end
                app.systemOnOff = "on";
                % Shotdown offline processing
                app.offline_processing = 0;
                % Change button and font colors
                app.OfflineProcessingButton.ImageSource = "ecg-lines-passive.png"; app.OfflineProcessingLabel.FontColor = "#c2c2c2";
                app.SystemOnOffButton.ImageSource = 'power.png'; app.RealtimeProcessingLabel.FontColor = '#6ebeff';
                app.HeartImage.ImageSource = 'heart_green_animated.svg';
                % Hide HRV button
                app.HRVButton.Visible = 0; app.HRVLabel.Visible = 0;
                % MATLAB - Arduino Serial Connection
                try
                    s = serialport(app.comPort, 115200);
                catch
                    s = [];
                    % Update the status
                    app.StatusLabel.Text = 'Cannot connect'; app.StatusIcon.ImageSource = 'error.svg';
                    % System  is 'off'
                    app.systemOnOff = 'off';
                    % Abort recording
                    app.recording = 0;
                    app.RecordButton.ImageSource = 'microphone_passive.svg'; app.RecordingLabel.FontColor = '#c2c2c2';
                    % Change button and font colors
                    app.SystemOnOffButton.ImageSource = 'power_passive.png'; app.RealtimeProcessingLabel.FontColor = '#c2c2c2';
                    app.HeartImage.ImageSource = 'heart_green.svg';
                    % Open warning dialog
                    uialert(app.ECGAnalysisSoftwareUIFigure, 'Connection is unsuccessful!', 'Warning');
                    return
                end
                
                % Clear plots
                cla(app.ECGPlot); cla(app.ECGFFTPlot); cla(app.FilteredECGPlot);
                app.FilteredECGPlot.XLim = [0 5]; app.ECGPlot.XLim = [0 5];
                
                % Get the first data
                flush(s);
                try
                    data = readline(s);
                catch exception
                    % do nothing
                end
                
                % Variable definitions
                frame = 2500;
                ECG = zeros(1,1000000) - 1; time = zeros(1,1000000) - 1;
                i = frame;
                k = 1;
                locs = [];
                plotECG = []; plotECGFFT = []; plotFilteredECG = [];
                % Plot the frequency response
                if(app.filtering)
                    freq_response = freqz(app.b, app.a, 1250);
                    app.plotFreqz = line(app.ECGFFTPlot,linspace(0,app.fs_fixed/2,1250),abs(freq_response), 'color', '#e04f5f');
                end
                % The main loop
                while app.systemOnOff == "on"
                    tic
                    % Get the data pack
                    for j=1:40
                        i = i + 1;
                        data = str2double(strsplit(readline(s), ","));
                        ECG(i) = data(1);
                        time(i) = data(2);
                    end
                    fs = (frame*1000000/(time(i)-time(i-frame))); % Calculate the sampling frequency
                    last_ECG = ECG(i-frame+1:i); % Get the last data frame
                    ECG_normalized = normalize(last_ECG); % Clear the offset
                    time_vector = 0:5/(frame-1):5; % Create the time vector [0 5]
                    % Plot the ECG signal
                    delete(plotECG);
                    plotECG = line(app.ECGPlot, time_vector, normalize(last_ECG, 'range'));
                    % Plot the FFT
                    delete(plotECGFFT);
                    plotECGFFT = line(app.ECGFFTPlot, linspace(0, fs, frame), normalize(abs(fft(ECG_normalized)), 'range'));
                    % Plot the filtered signal
                    if app.filtering == 1
                        ECG_filtered = filtfilt(app.b, app.a, last_ECG);
                        delete(plotFilteredECG);
                        plotFilteredECG  = line(app.FilteredECGPlot, time_vector, normalize(ECG_filtered, 'range'));
                    end
                    
                    % Calculate the BPM
                    if mod(k,20) == 0
                        if i > 5000
                            ECG_peak = filtfilt(app.bpm_b, app.bpm_a, ECG(i-5000:i)); % Filtering
                            ECG_peak = ECG_peak - movmean(ECG_peak, 70); % Linearization
                            ECG_peak = ECG_peak.^3;
                            ECG_peak(ECG_peak < 0) = 0;
                            ECG_windowed_normalized = [normalize(ECG_peak(1:1250), 'range') normalize(ECG_peak(1251:2500), 'range') normalize(ECG_peak(2501:3750), 'range') normalize(ECG_peak(3751:5000), 'range')];
                            [~, locs] = findpeaks(ECG_windowed_normalized, fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                            locs_length = length(locs);
                            if locs_length > 1
                                beats = 60*(locs_length-1)/(locs(end) - locs(1));
                                app.BPM.Text = num2str(round(beats));
                            end
                            
                        end
                        app.Bytes.Text = num2str(s.NumBytesAvailable);
                        app.Fs.Text = num2str(fs,'%.2f');
                    end
                    k = k+1;
                    drawnow limitrate
                    while toc < 0.075
                        %Wait here to stabilize the plotting visually
                    end
                end
                % If recording was open, save ECG and time data
                if app.recording == 1
                    cd(app.save_path);
                    ECG(ECG == -1) = []; time(time == -1) = [];
                    save(['ECG_' datestr(now,30)],'ECG', 'time');
                    app.recording = 0;
                    % Change heart icon to static
                    app.HeartImage.ImageSource = 'heart_green.svg';
                end
                % Update the status
                app.StatusLabel.Text = 'Ready to launch'; app.StatusIcon.ImageSource = 'ready.svg';
            else
                app.systemOnOff = "off";
                % Change button and font colors
                app.SystemOnOffButton.ImageSource = 'power_passive.png'; app.RealtimeProcessingLabel.FontColor = '#c2c2c2';
                app.HeartImage.ImageSource = 'heart_green.svg';
                % Update status
                app.StatusLabel.Text = 'Ready'; app.StatusIcon.ImageSource = 'ready.svg';
                % Abort recording
                app.recording = 0;
                app.RecordButton.ImageSource = 'microphone_passive.svg'; app.RecordingLabel.FontColor = '#c2c2c2';
            end
        end

        % Image clicked function: OfflineProcessingButton
        function OfflineProcessing(app, event)
            if app.recording == 0
                app.offline_processing = 1;
                % Update status
                app.StatusLabel.Text = 'Busy'; app.StatusIcon.ImageSource = 'waiting.gif'; drawnow;
                delete(app.DialogApp_choose_ecg)
                % Stop real-time processing
                app.systemOnOff = 'off';
                app.SystemOnOffButton.ImageSource = 'power_passive.png'; app.RealtimeProcessingLabel.FontColor = '#c2c2c2';
                % Clear plots
                cla(app.ECGPlot); cla(app.ECGFFTPlot); cla(app.FilteredECGPlot);
                %% Reset axes
                % Set line colors for the three UIAxes
                app.ECGPlot.ColorOrder =  [0.74, 1, 0.59];
                app.ECGFFTPlot.ColorOrder =  [0.77, 0.98, 1];
                app.FilteredECGPlot.ColorOrder =  [0.74, 1, 0.59];
                
                % Set axis limits
                app.ECGPlot.XLim = [0 5]; app.ECGPlot.YLim = [-0.2 1.2];
                app.ECGFFTPlot.XLim = [0 150]; app.ECGFFTPlot.YLim = [0 1.2];
                app.FilteredECGPlot.XLim = [0 5]; app.FilteredECGPlot.YLim = [-0.2 1.2];
                % Activate filtering
                app.filtering = 1;
                app.StartButton.ImageSource = 'filter_on.svg'; app.FilteringStatusLabel.Text = ' Filtering is on';
                % Reset Bpm Value
                app.BPM.Text = '···';
                app.HeartImage.ImageSource = 'heart_green.svg';
                % Change Button and font colors
                app.OfflineProcessingButton.ImageSource = "ecg-lines.svg"; app.OfflineProcessingLabel.FontColor = '#6ebeff';
                % Open choose_ecg.mlapp
                app.DialogApp_choose_ecg = choose_ecg(app);
                % Update status
                app.StatusLabel.Text = 'Ready'; app.StatusIcon.ImageSource = 'ready.svg';
            end
        end

        % Close request function: ECGAnalysisSoftwareUIFigure
        function MainAppCloseRequest(app, event)
            % When main app is closed, close other apps as well
            delete(app.DialogApp_choose_port)
            delete(app.DialogApp_choose_ecg)
            delete(app.DialogApp_hrv_monitor)
            delete(app)
        end

        % Image clicked function: RecordButton
        function RecordingButtonClicked(app, event)
            if app.recording == 1
                % Update status
                app.StatusLabel.Text = 'Ready'; app.StatusIcon.ImageSource = 'ready.svg';
                % Stop real-time processing
                app.systemOnOff = 'off';
                app.SystemOnOffButton.ImageSource = 'power_passive.png'; app.RealtimeProcessingLabel.FontColor = '#c2c2c2';
                app.RecordButton.ImageSource = 'microphone_passive.svg'; app.RecordingLabel.FontColor = '#c2c2c2';
            else % Initialise the recording
                % Update status
                app.StatusLabel.Text = 'Recording'; app.StatusIcon.ImageSource = 'record.svg';
                % Stop real-time processing
                app.systemOnOff = 'off';
                app.SystemOnOffButton.ImageSource = 'power_passive.png'; app.RealtimeProcessingLabel.FontColor = '#c2c2c2';
                app.RecordButton.ImageSource = 'microphone.svg'; app.RecordingLabel.FontColor = '#6ebeff';
                app.recording = 1;
                % Start the system
                SystemOnOffButtonClicked(app);
            end
        end

        % Image clicked function: HRVButton
        function HRVButtonPushed(app, event)
            delete(app.DialogApp_hrv_monitor)
            app.DialogApp_hrv_monitor = hrv_monitor(app.ECG_offline, app.time_offline);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECGAnalysisSoftwareUIFigure = uifigure('Visible', 'off');
            app.ECGAnalysisSoftwareUIFigure.Name = 'ECG Analysis Software';
            app.ECGAnalysisSoftwareUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.GridLayout = uigridlayout(app.ECGAnalysisSoftwareUIFigure);
            app.FilteringDropDown = uidropdown(app.GridLayout);
            app.FilteringDropDown.Items = {'Butterworth', 'Chebychev', 'Elliptic'};
            app.FilteringDropDown.ValueChangedFcn = createCallbackFcn(app, @filteringOptionsChanged, true);
            app.FilteringDropDown.Value = 'Butterworth';
            app.FilterTypeKnob = uiknob(app.GridLayout, 'discrete');
            app.FilterTypeKnob.Items = {'Highpass', 'Lowpass', 'Bandpass', 'Bandstop'};
            app.FilterTypeKnob.ValueChangedFcn = createCallbackFcn(app, @filteringOptionsChanged, true);
            app.FilterTypeKnob.Value = 'Lowpass';
            app.OrderKnobLabel = uilabel(app.GridLayout);
            app.OrderKnobLabel.Text = 'Order';
            app.OrderKnob = uiknob(app.GridLayout, 'discrete');
            app.OrderKnob.Items = {'1', '2', '3', '4', '5', '6', '7'};
            app.OrderKnob.ValueChangedFcn = createCallbackFcn(app, @filteringOptionsChanged, true);
            app.OrderKnob.Value = '3';
            app.RpKnobLabel = uilabel(app.GridLayout);
            app.RpKnobLabel.Text = 'Rp';
            app.RpKnob = uiknob(app.GridLayout, 'discrete');
            app.RpKnob.Items = {'1', '2', '3', '4', '5', '6', '7'};
            app.RpKnob.ValueChangedFcn = createCallbackFcn(app, @filteringOptionsChanged, true);
            app.RpKnob.Tooltip = {'Passband ripple'};
            app.RpKnob.Value = '1';
            app.RsKnobLabel = uilabel(app.GridLayout);
            app.RsKnobLabel.Text = 'Rs';
            app.RsKnob = uiknob(app.GridLayout, 'discrete');
            app.RsKnob.Items = {'1', '2', '3', '4', '5', '6', '7'};
            app.RsKnob.ValueChangedFcn = createCallbackFcn(app, @filteringOptionsChanged, true);
            app.RsKnob.Tooltip = {'Stopband attenuation'};
            app.RsKnob.Value = '2';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Busy';
            app.Switch = uiswitch(app.GridLayout, 'rocker');
            app.Switch.Visible = 'off';
            app.BPM = uilabel(app.GridLayout);
            app.BPM.Text = '···';
            app.HeartImage = uiimage(app.GridLayout);
            app.HeartImage.ImageSource = 'heart_green.svg';
            app.BPMLabel = uilabel(app.GridLayout);
            app.BPMLabel.Text = 'BPM';
            app.ECGFFTPlot = uiaxes(app.GridLayout);
            title(app.ECGFFTPlot, '')
            xlabel(app.ECGFFTPlot, 'Frequency (Hz)')
            ylabel(app.ECGFFTPlot, 'Amplitude')
            app.ECGFFTPlot.XLim = [0 150];
            app.ECGFFTPlot.YLim = [0 1.2];
            app.ECGFFTPlot.XTick = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150];
            app.FFToftheECGSignalLabel = uilabel(app.GridLayout);
            app.FFToftheECGSignalLabel.Text = 'FFT of the ECG Signal';
            app.StartButton = uiimage(app.GridLayout);
            app.StartButton.ImageClickedFcn = createCallbackFcn(app, @execute, true);
            app.StartButton.ImageSource = 'filter_on.svg';
            app.Wp1Spinner = uispinner(app.GridLayout);
            app.Wp1Spinner.Step = 0.5;
            app.Wp1Spinner.Limits = [0 250];
            app.Wp1Spinner.ValueChangedFcn = createCallbackFcn(app, @filteringOptionsChanged, true);
            app.Wp1Spinner.Value = 20;
            app.Wp2Spinner = uispinner(app.GridLayout);
            app.Wp2Spinner.Step = 0.5;
            app.Wp2Spinner.Limits = [0 250];
            app.Wp2Spinner.ValueChangedFcn = createCallbackFcn(app, @filteringOptionsChanged, true);
            app.Wp2Spinner.Value = 30;
            app.LowerCFLabel = uilabel(app.GridLayout);
            app.LowerCFLabel.Text = 'Lower CF';
            app.HigherCFLabel = uilabel(app.GridLayout);
            app.HigherCFLabel.Text = 'Higher CF';
            app.FilteringStatusLabel = uilabel(app.GridLayout);
            app.FilteringStatusLabel.Text = ' Filtering is on';
            app.DigitalFiltersLabel = uilabel(app.GridLayout);
            app.DigitalFiltersLabel.Text = 'Digital Filters';
            app.StatusIcon = uiimage(app.GridLayout);
            app.StatusIcon.ImageSource = 'waiting.gif';
            app.Fs = uilabel(app.GridLayout);
            app.Fs.Text = '···';
            app.Bytes = uilabel(app.GridLayout);
            app.Bytes.Text = '···';
            app.HzLabel = uilabel(app.GridLayout);
            app.HzLabel.Text = 'Hz';
            app.BytesLabel = uilabel(app.GridLayout);
            app.BytesLabel.Text = 'Bytes';
            app.PortIcon = uiimage(app.GridLayout);
            app.PortIcon.ImageClickedFcn = createCallbackFcn(app, @SelectPort, true);
            app.PortIcon.ImageSource = 'plug.svg';
            app.SystemOnOffButton = uiimage(app.GridLayout);
            app.SystemOnOffButton.ImageClickedFcn = createCallbackFcn(app, @SystemOnOffButtonClicked, true);
            app.SystemOnOffButton.ImageSource = 'power_passive.png';
            app.COMLabel = uilabel(app.GridLayout);
            app.COMLabel.Text = 'COM9';
            app.RecordButton = uiimage(app.GridLayout);
            app.RecordButton.ImageClickedFcn = createCallbackFcn(app, @RecordingButtonClicked, true);
            app.RecordButton.ImageSource = 'microphone_passive.svg';
            app.RecordingLabel = uilabel(app.GridLayout);
            app.RecordingLabel.Text = 'Recording';
            app.OfflineProcessingButton = uiimage(app.GridLayout);
            app.OfflineProcessingButton.ImageClickedFcn = createCallbackFcn(app, @OfflineProcessing, true);
            app.OfflineProcessingButton.ImageSource = 'ecg-lines-passive.png';
            app.OfflineProcessingLabel = uilabel(app.GridLayout);
            app.OfflineProcessingLabel.Text = {'Offline'; 'Processing'};
            app.RealtimeProcessingLabel = uilabel(app.GridLayout);
            app.RealtimeProcessingLabel.Text = {'Real-time'; 'Processing'};
            app.ECGPlot = uiaxes(app.GridLayout);
            title(app.ECGPlot, ' ')
            xlabel(app.ECGPlot, 'Time (s)')
            ylabel(app.ECGPlot, 'Amplitude')
            app.ECGPlot.XLim = [0 5];
            app.ECGPlot.YLim = [-0.3 1.3];
            app.ECGPlot.YTick = [0 0.2 0.4 0.6 0.8 1 1.2];
            app.RealtimeECGSignalLabel = uilabel(app.GridLayout);
            app.RealtimeECGSignalLabel.Text = 'Real-time ECG Signal';
            app.FilteredECGPlot = uiaxes(app.GridLayout);
            title(app.FilteredECGPlot, ' ')
            xlabel(app.FilteredECGPlot, 'Time (s)')
            ylabel(app.FilteredECGPlot, 'Amplitude')
            app.FilteredECGPlot.XLim = [0 5];
            app.FilteredECGPlot.YLim = [-0.3 1.3];
            app.FilteredECGPlot.YTick = [0 0.2 0.4 0.6 0.8 1 1.2];
            app.RealtimeFilteredECGSignalLabel = uilabel(app.GridLayout);
            app.RealtimeFilteredECGSignalLabel.Text = 'Real-time Filtered ECG Signal';
            app.HRVButton = uiimage(app.GridLayout);
            app.HRVButton.ImageClickedFcn = createCallbackFcn(app, @HRVButtonPushed, true);
            app.HRVButton.Visible = 'off';
            app.HRVButton.ImageSource = 'hrv.svg';
            app.HRVLabel = uilabel(app.GridLayout);
            app.HRVLabel.Visible = 'off';
            app.HRVLabel.Text = 'H R V';
            % Show the figure after all components are created
            app.ECGAnalysisSoftwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_real_time_signal_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGAnalysisSoftwareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGAnalysisSoftwareUIFigure)
        end
    end
end