classdef choose_ecg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChooseECGUIFigure       matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        SelectanECGsignalLabel  matlab.ui.control.Label
        FilesDropDown           matlab.ui.control.DropDown
        Image                   matlab.ui.control.Image
        StarttimesSpinnerLabel  matlab.ui.control.Label
        StarttimeSpinner        matlab.ui.control.Spinner
        RecorddurationLabel     matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        EndtimesSpinnerLabel    matlab.ui.control.Label
        EndtimeSpinner          matlab.ui.control.Spinner
        SetatimeintervalLabel   matlab.ui.control.Label
        DurationLabel           matlab.ui.control.Label
        ChoosePathButton        matlab.ui.control.Image
    end

    
    properties (Access = private)
        path % directory
        CallingApp
    end
    
    methods (Access = private)
        
        function calculateDuration(app)
            try
                file = dir(app.FilesDropDown.Value); % Get the file
                load(file.name); % Load the file
                duration = (time(end)-time(1))/1000000; % Calculate the record duration
                app.EndtimeSpinner.Value = duration;
                app.DurationLabel.Text = strcat(num2str(duration), ' Seconds');
            catch
                % do nothing
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ChooseECGStartUp(app, mainapp)
            app.CallingApp = mainapp;
            load('configurations.mat');
            cd(conf.load_path);
            app.path = conf.load_path;
            files = dir('*.mat');
            if ~isempty(files)
                for i=1:length(files)
                    fileCell{i} = files(i).name;
                end
                app.FilesDropDown.Items = fileCell;
                calculateDuration(app);
                app.path = conf.load_path;
            else
                app.FilesDropDown.Value = 'Choose a folder';
            end
        end

        % Image clicked function: Image
        function ChoosePath(app, event)
            app.FilesDropDown.Items = {};
            app.path = uigetdir;
            cd(app.path);
            files = dir('*.mat');
            if ~isempty(files)
                for i=1:length(files)
                    fileCell{i} = files(i).name;
                end
                app.FilesDropDown.Items = fileCell;
            end
            % Focus on the figure
            figure(app.ChooseECGUIFigure);
            calculateDuration(app);
        end

        % Image clicked function: ChoosePathButton
        function ChoosePathButtonClicked(app, event)
            loadECG(app.CallingApp, app.path, app.FilesDropDown.Value, app.StarttimeSpinner.Value, app.EndtimeSpinner.Value);
        end

        % Value changed function: FilesDropDown
        function FilesDropDownValueChanged(app, event)
            calculateDuration(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChooseECGUIFigure = uifigure('Visible', 'off');
            app.ChooseECGUIFigure.Name = 'Choose ECG';
            app.GridLayout = uigridlayout(app.ChooseECGUIFigure);
            app.SelectanECGsignalLabel = uilabel(app.GridLayout);
            app.SelectanECGsignalLabel.Text = 'Select an ECG signal';
            app.FilesDropDown = uidropdown(app.GridLayout);
            app.FilesDropDown.Items = {};
            app.FilesDropDown.ValueChangedFcn = createCallbackFcn(app, @FilesDropDownValueChanged, true);
            app.FilesDropDown.Value = {};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ChoosePath, true);
            app.Image.ImageSource = 'folder.svg';
            app.StarttimesSpinnerLabel = uilabel(app.GridLayout);
            app.StarttimesSpinnerLabel.Text = 'Start time (s)';
            app.StarttimeSpinner = uispinner(app.GridLayout);
            app.StarttimeSpinner.Limits = [0 Inf];
            app.RecorddurationLabel = uilabel(app.GridLayout);
            app.RecorddurationLabel.Text = 'Record duration:';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'ecg-lines.svg';
            app.EndtimesSpinnerLabel = uilabel(app.GridLayout);
            app.EndtimesSpinnerLabel.Text = 'End time (s)';
            app.EndtimeSpinner = uispinner(app.GridLayout);
            app.EndtimeSpinner.Limits = [0 Inf];
            app.SetatimeintervalLabel = uilabel(app.GridLayout);
            app.SetatimeintervalLabel.Text = {'Set a time'; 'interval'};
            app.DurationLabel = uilabel(app.GridLayout);
            app.DurationLabel.Text = '···';
            app.ChoosePathButton = uiimage(app.GridLayout);
            app.ChoosePathButton.ImageClickedFcn = createCallbackFcn(app, @ChoosePathButtonClicked, true);
            app.ChoosePathButton.ImageSource = 'filter_on.svg';
            % Show the figure after all components are created
            app.ChooseECGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = choose_ecg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChooseECGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)ChooseECGStartUp(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChooseECGUIFigure)
        end
    end
end