classdef hrv_monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HRVAnalysisUIFigure           matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        RRTachograminterpolatedLabel  matlab.ui.control.Label
        PSDWelcheMethodLabel          matlab.ui.control.Label
        VLFpercent                    matlab.ui.control.Label
        VLFLabel                      matlab.ui.control.Label
        LFLabel                       matlab.ui.control.Label
        HFLabel                       matlab.ui.control.Label
        VHFLabel                      matlab.ui.control.Label
        LFpercent                     matlab.ui.control.Label
        HFpercent                     matlab.ui.control.Label
        VHFpercent                    matlab.ui.control.Label
        PSDPlot                       matlab.ui.control.UIAxes
        RRTachogramPlot               matlab.ui.control.UIAxes
        PercentageofFrequencyComponentsLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ECG, time)
            %             load('ECG_20191219T180221.mat');
            %             time = time - time(1); time = time/1000000;
            e = normalize(ECG, 'range');
            fs = (length(ECG)-1)/(time(end)-time(1));
            
            % Default lowpass filter coefficients at 13 Hz with a order of 3 (BPM)
            bpm_a = [1.0000 -2.6736 2.3984 -0.7210];
            bpm_b = [0.0005 0.0014 0.0014 0.0005];
            
            
            % Calculate BPM
            ECG_peak = filtfilt(bpm_b, bpm_a, ECG); % Filtering
            ECG_peak = ECG_peak - movmean(ECG_peak, 70); % Linearization
            ECG_peak = ECG_peak.^3;
            ECG_peak(ECG_peak < 0) = 0;
            ECG_peak = normalize(ECG_peak, 'range');
            % Moving window bpm calculation
            try
                locs = [];
                window = 1000;
                i = window;
                l = length(ECG_peak) + window;
                while (true)
                    if l - window > i
                        [~, locs_temp] = findpeaks(normalize(ECG_peak(window-i+1:window), 'range'), fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                        locs = [locs (locs_temp + (window-i)/fs)];
                        window = window + i;
                    else
                        [~, locs_temp] = findpeaks(normalize(ECG_peak(window-i+1:end), 'range'), fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                        locs = [locs (locs_temp + (window-i)/fs)];
                        break;
                    end
                end
            catch
                locs = [];
                window = 1600;
                i = window;
                l = length(ECG_peak) + window;
                while (true)
                    if l - window > i
                        [~, locs_temp] = findpeaks(normalize(ECG_peak(window-i+1:window), 'range'), fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                        locs = [locs (locs_temp + (window-i)/fs)];
                        window = window + i;
                    else
                        [~, locs_temp] = findpeaks(normalize(ECG_peak(window-i+1:end), 'range'), fs, 'MinPeakDistance', 0.3, 'MinPeakHeight', 0.2);
                        locs = [locs (locs_temp + (window-i)/fs)];
                        break;
                    end
                end
            end
            locs = locs + 0.00601; % adding the delay caused by filtering
            locs_length = length(locs);
            
            HRV = [];
            for i = 2:length(locs)
                HRV = [HRV locs(i)-locs(i-1)];
            end
            
            peak_time = locs(2:end);
            tp = .1; fs2 = 1/tp;
            interp_time = 0:tp:peak_time(end);
            % Interpolation
            interp_HRV = interp1(peak_time,HRV,interp_time, 'spline');
            plot(app.RRTachogramPlot,interp_time,interp_HRV,'*', 'Color', '#c4faff', 'MarkerSize', 5);
            hold(app.RRTachogramPlot, 'on');
            plot(app.RRTachogramPlot, peak_time, HRV, 'o', 'MarkerEdgeColor', '#e04f5f', 'MarkerFaceColor', '#e04f5f', 'MarkerSize', 5);
            app.RRTachogramPlot.XLim = [0 peak_time(end)];
            [pxx,f] = pwelch(interp_HRV-mean(interp_HRV),[],[],[],fs2);
            interp_hz = 0:tp/20:fs2;
            interp_fft = interp1(f,pxx,interp_hz, 'spline');
            region = area(app.PSDPlot,0:0.005:0.05, interp_fft(1:11));
            region.FaceAlpha = .6;
            region.FaceColor = '#1797ff';
            region.EdgeColor = 'none';
            hold(app.PSDPlot, 'on')
            region = area(app.PSDPlot,0.05:0.005:0.15, interp_fft(11:31));
            region.FaceAlpha = 0.6;
            region.FaceColor = '#e04f5f';
            region.EdgeColor = 'none';
            hold(app.PSDPlot, 'on')
            region = area(app.PSDPlot,0.15:0.005:0.4, interp_fft(31:81));
            region.FaceAlpha = 0.6;
            region.FaceColor = '#bdff96';
            region.EdgeColor = 'none';
            hold(app.PSDPlot, 'on')
            plot(app.PSDPlot,interp_hz,interp_fft,"Color",[1 0 0]);
            region = area(app.PSDPlot,0.4:0.005:0.5, interp_fft(81:101));
            region.FaceAlpha = 0.6;
            region.FaceColor = '#FFBB00';
            region.EdgeColor = 'none';
            hold(app.PSDPlot, 'on')
            plot(app.PSDPlot,interp_hz,interp_fft,"Color", '#c4faff', 'LineWidth', 2.5);
            
            % For calculating percentage
            VLF = sum(interp_fft(1:11));
            LF =  sum(interp_fft(11:31));
            HF =  sum(interp_fft(31:81));
            VHF = sum(interp_fft(81:101));
            
            app.VLFpercent.Text = num2str(VLF/(VLF+LF+HF+VHF)*100,'%.2f') + "%";
            app.LFpercent.Text  = num2str(LF/(VLF+LF+HF+VHF)*100,'%.2f')  + "%";
            app.HFpercent.Text  = num2str(HF/(VLF+LF+HF+VHF)*100,'%.2f')  + "%";
            app.VHFpercent.Text = num2str(VHF/(VLF+LF+HF+VHF)*100,'%.2f') + "%";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HRVAnalysisUIFigure = uifigure('Visible', 'off');
            app.HRVAnalysisUIFigure.Name = 'HRV Analysis';
            app.GridLayout = uigridlayout(app.HRVAnalysisUIFigure);
            app.RRTachograminterpolatedLabel = uilabel(app.GridLayout);
            app.RRTachograminterpolatedLabel.Text = 'RR Tachogram (interpolated)';
            app.PSDWelcheMethodLabel = uilabel(app.GridLayout);
            app.PSDWelcheMethodLabel.Text = 'PSD (Welche Method)';
            app.VLFpercent = uilabel(app.GridLayout);
            app.VLFpercent.Tooltip = {'Reflects the input from chemoreceptors, thermoreceptors, the reninangiotensin system'};
            app.VLFpercent.Text = '-';
            app.VLFLabel = uilabel(app.GridLayout);
            app.VLFLabel.Tooltip = {'Very low frequency'};
            app.VLFLabel.Text = 'VLF';
            app.LFLabel = uilabel(app.GridLayout);
            app.LFLabel.Tooltip = {'Low frequecy'};
            app.LFLabel.Text = 'LF';
            app.HFLabel = uilabel(app.GridLayout);
            app.HFLabel.Tooltip = {'High frequency'};
            app.HFLabel.Text = 'HF';
            app.VHFLabel = uilabel(app.GridLayout);
            app.VHFLabel.Tooltip = {'Very high frequency'};
            app.VHFLabel.Text = 'VHF';
            app.LFpercent = uilabel(app.GridLayout);
            app.LFpercent.Tooltip = {'Reflects sympathetic activity'};
            app.LFpercent.Text = '-';
            app.HFpercent = uilabel(app.GridLayout);
            app.HFpercent.Tooltip = {'Reflects the activity of the parasympathetic system'; 'and the vagus nerve'};
            app.HFpercent.Text = '-';
            app.VHFpercent = uilabel(app.GridLayout);
            app.VHFpercent.Text = '-';
            app.PSDPlot = uiaxes(app.GridLayout);
            title(app.PSDPlot, '')
            xlabel(app.PSDPlot, 'Frequency (Hz)')
            ylabel(app.PSDPlot, 'Power / Frequency (s² / Hz)')
            app.PSDPlot.XLim = [0 0.5];
            app.PSDPlot.XTick = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5];
            app.RRTachogramPlot = uiaxes(app.GridLayout);
            title(app.RRTachogramPlot, '')
            xlabel(app.RRTachogramPlot, 'Time (s)')
            ylabel(app.RRTachogramPlot, 'RR Interval (s)')
            app.PercentageofFrequencyComponentsLabel = uilabel(app.GridLayout);
            app.PercentageofFrequencyComponentsLabel.Text = 'Percentage of Frequency Components';
            % Show the figure after all components are created
            app.HRVAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hrv_monitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HRVAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HRVAnalysisUIFigure)
        end
    end
end